classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MASSimulationUIFigure           matlab.ui.Figure
        ChoixduCoupleButtonGroup_2      matlab.ui.container.ButtonGroup
        KcCnWnButton                    matlab.ui.control.RadioButton
        ConstantButton_2                matlab.ui.control.RadioButton
        AvideButton_2                   matlab.ui.control.RadioButton
        RsistanceVariableSwitchLabel    matlab.ui.control.Label
        ResistanceVariableSwitch        matlab.ui.control.Switch
        LEDLamp                         matlab.ui.control.Lamp
        Un400VNn2972trminCn643NmLabel   matlab.ui.control.Label
        SIMULATIONButton                matlab.ui.control.Button
        MACHINEASYNCHRONEENTRAINEMENTAVITESSEVARIABLELabel  matlab.ui.control.Label
        NreftrminSliderLabel            matlab.ui.control.Label
        NreftrminSlider                 matlab.ui.control.Slider
        CoupleNmSliderLabel             matlab.ui.control.Label
        CoupleNmSlider                  matlab.ui.control.Slider
        Label3                          matlab.ui.control.Label
        NrefEditFieldLabel              matlab.ui.control.Label
        NrefEditField                   matlab.ui.control.NumericEditField
        CoupleEditFieldLabel            matlab.ui.control.Label
        CoupleEditField                 matlab.ui.control.NumericEditField
        Lamp                            matlab.ui.control.Lamp
        TempsdeSimulationEditFieldLabel  matlab.ui.control.Label
        TempsdeSimulationEditField      matlab.ui.control.NumericEditField
        secondesLabel                   matlab.ui.control.Label
        trminLabel                      matlab.ui.control.Label
        NmLabel                         matlab.ui.control.Label
        GainduconvertisseurSwitchLabel  matlab.ui.control.Label
        GainduconvertisseurSwitch       matlab.ui.control.Switch
    end

    methods (Access = private)

        % Value changed function: ResistanceVariableSwitch
        function ResistanceVariableSwitchValueChanged(app, event)
            value = app.ResistanceVariableSwitch.Value;
            if strcmp(value,'On')
                assignin('base','decider_R',0);
                app.LEDLamp.Color = 'r';
            else
                assignin('base','decider_R',1);
                app.LEDLamp.Color = 'w';
            end
        end

        % Button pushed function: SIMULATIONButton
        function SIMULATIONButtonPushed(app, event)
            app.Lamp.Color = 'g';
            app.SIMULATIONButton.Text = "Loading ...";
            app.SIMULATIONButton.BackgroundColor = 'w';
            app.SIMULATIONButton.FontColor = 'k';
             if app.KcCnWnButton.Value == true
                assignin('base','deciderC',1);
                assignin('base','deciderC2',1);
            end
            if app.ConstantButton_2.Value == true
                assignin('base','deciderC',0);
                assignin('base','deciderC2',1);
            end
            if app.AvideButton_2.Value == true
                assignin('base','deciderC',0);
                assignin('base','deciderC2',0);
            end
            valeur_Vitesse = app.NreftrminSlider.Value;
            assignin('base','N_in',valeur_Vitesse);
            valeur_Couple = app.CoupleNmSlider.Value;
            assignin('base','C_in',valeur_Couple);
            tps_sim = app.TempsdeSimulationEditField.Value;
            assignin('base','tps_sim',tps_sim);
            run('visualisation.m');
            app.SIMULATIONButton.Text = "SIMULATION";
            app.SIMULATIONButton.BackgroundColor = 'r';
            app.SIMULATIONButton.FontColor = 'w';
            app.Lamp.Color = 'w';
        end

        % Value changed function: NreftrminSlider
        function NreftrminSliderValueChanged(app, event)

            l = app.NreftrminSlider.Value;
            app.NrefEditField.Value = l;
        end

        % Value changed function: CoupleNmSlider
        function CoupleNmSliderValueChanged(app, event)
           
            a = app.CoupleNmSlider.Value;
            app.CoupleEditField.Value = a;
        end

        % Value changed function: NrefEditField
        function NrefEditFieldValueChanged(app, event)
            app.NrefEditField.Limits = [0 2972];
            value = app.NrefEditField.Value;
            app.NreftrminSlider.Value = value;
        end

        % Value changed function: CoupleEditField
        function CoupleEditFieldValueChanged(app, event)
            app.CoupleEditField.Limits = [0 643];
            value = app.CoupleEditField.Value;
            app.CoupleNmSlider.Value = value;
        end

        % Value changed function: TempsdeSimulationEditField
        function TempsdeSimulationEditFieldValueChanged(app, event)
            app.TempsdeSimulationEditField.Limits = [0 inf];
        end

        % Value changed function: GainduconvertisseurSwitch
        function GainduconvertisseurSwitchValueChanged(app, event)
            value = app.GainduconvertisseurSwitch.Value;
            if strcmp(value,'1')
                assignin('base', 'k0', 1);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MASSimulationUIFigure = uifigure;
            app.MASSimulationUIFigure.Name = 'MAS Simulation';
            app.ChoixduCoupleButtonGroup_2 = uibuttongroup(app.MASSimulationUIFigure);
            app.ChoixduCoupleButtonGroup_2.Title = 'Choix du Couple';
            app.KcCnWnButton = uiradiobutton(app.ChoixduCoupleButtonGroup_2);
            app.KcCnWnButton.Text = 'Kc (Cn/Wn)';
            app.KcCnWnButton.Value = true;
            app.ConstantButton_2 = uiradiobutton(app.ChoixduCoupleButtonGroup_2);
            app.ConstantButton_2.Text = 'Constant';
            app.AvideButton_2 = uiradiobutton(app.ChoixduCoupleButtonGroup_2);
            app.AvideButton_2.Text = 'A vide ';
            app.RsistanceVariableSwitchLabel = uilabel(app.MASSimulationUIFigure);
            app.RsistanceVariableSwitchLabel.Text = 'Résistance Variable ';
            app.ResistanceVariableSwitch = uiswitch(app.MASSimulationUIFigure, 'slider');
            app.ResistanceVariableSwitch.ValueChangedFcn = createCallbackFcn(app, @ResistanceVariableSwitchValueChanged, true);
            app.LEDLamp = uilamp(app.MASSimulationUIFigure);
            app.Un400VNn2972trminCn643NmLabel = uilabel(app.MASSimulationUIFigure);
            app.Un400VNn2972trminCn643NmLabel.Text = 'Un = 400 V | Nn = 2972 tr/min | Cn = 643 N.m';
            app.SIMULATIONButton = uibutton(app.MASSimulationUIFigure, 'push');
            app.SIMULATIONButton.ButtonPushedFcn = createCallbackFcn(app, @SIMULATIONButtonPushed, true);
            app.SIMULATIONButton.Text = 'SIMULATION';
            app.MACHINEASYNCHRONEENTRAINEMENTAVITESSEVARIABLELabel = uilabel(app.MASSimulationUIFigure);
            app.MACHINEASYNCHRONEENTRAINEMENTAVITESSEVARIABLELabel.Text = 'MACHINE ASYNCHRONE ENTRAINEMENT A VITESSE VARIABLE';
            app.NreftrminSliderLabel = uilabel(app.MASSimulationUIFigure);
            app.NreftrminSliderLabel.Text = 'Nref | tr/min';
            app.NreftrminSlider = uislider(app.MASSimulationUIFigure);
            app.NreftrminSlider.Limits = [0 2972];
            app.NreftrminSlider.ValueChangedFcn = createCallbackFcn(app, @NreftrminSliderValueChanged, true);
            app.NreftrminSlider.Value = 2972;
            app.CoupleNmSliderLabel = uilabel(app.MASSimulationUIFigure);
            app.CoupleNmSliderLabel.Text = 'Couple | N.m';
            app.CoupleNmSlider = uislider(app.MASSimulationUIFigure);
            app.CoupleNmSlider.Limits = [0 643];
            app.CoupleNmSlider.ValueChangedFcn = createCallbackFcn(app, @CoupleNmSliderValueChanged, true);
            app.CoupleNmSlider.Value = 643;
            app.Label3 = uilabel(app.MASSimulationUIFigure);
            app.Label3.Text = 'Après pression du bouton simulation merci de patienter, le fichier Simulink prend du temps pour s''executer';
            app.NrefEditFieldLabel = uilabel(app.MASSimulationUIFigure);
            app.NrefEditFieldLabel.Text = 'Nref';
            app.NrefEditField = uieditfield(app.MASSimulationUIFigure, 'numeric');
            app.NrefEditField.ValueChangedFcn = createCallbackFcn(app, @NrefEditFieldValueChanged, true);
            app.NrefEditField.Value = 2972;
            app.CoupleEditFieldLabel = uilabel(app.MASSimulationUIFigure);
            app.CoupleEditFieldLabel.Text = 'Couple';
            app.CoupleEditField = uieditfield(app.MASSimulationUIFigure, 'numeric');
            app.CoupleEditField.ValueChangedFcn = createCallbackFcn(app, @CoupleEditFieldValueChanged, true);
            app.CoupleEditField.Value = 643;
            app.Lamp = uilamp(app.MASSimulationUIFigure);
            app.TempsdeSimulationEditFieldLabel = uilabel(app.MASSimulationUIFigure);
            app.TempsdeSimulationEditFieldLabel.Text = 'Temps de Simulation';
            app.TempsdeSimulationEditField = uieditfield(app.MASSimulationUIFigure, 'numeric');
            app.TempsdeSimulationEditField.ValueChangedFcn = createCallbackFcn(app, @TempsdeSimulationEditFieldValueChanged, true);
            app.TempsdeSimulationEditField.Value = 10;
            app.secondesLabel = uilabel(app.MASSimulationUIFigure);
            app.secondesLabel.Text = 'secondes';
            app.trminLabel = uilabel(app.MASSimulationUIFigure);
            app.trminLabel.Text = 'tr/min';
            app.NmLabel = uilabel(app.MASSimulationUIFigure);
            app.NmLabel.Text = 'N.m';
            app.GainduconvertisseurSwitchLabel = uilabel(app.MASSimulationUIFigure);
            app.GainduconvertisseurSwitchLabel.Text = 'Gain du convertisseur';
            app.GainduconvertisseurSwitch = uiswitch(app.MASSimulationUIFigure, 'slider');
            app.GainduconvertisseurSwitch.Items = {'Vn*sqrt(2)/5', '1'};
            app.GainduconvertisseurSwitch.ValueChangedFcn = createCallbackFcn(app, @GainduconvertisseurSwitchValueChanged, true);
            app.GainduconvertisseurSwitch.Value = 'Vn*sqrt(2)/5';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MASSimulationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MASSimulationUIFigure)
        end
    end
end